classdef interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SelectButton                  matlab.ui.control.Button
        ImageDirecoryLabel            matlab.ui.control.Label
        ImageDirectoryEditField       matlab.ui.control.EditField
        GeometricTransformationPanel  matlab.ui.container.Panel
        figure1                       matlab.ui.control.UIAxes
        figure2                       matlab.ui.control.UIAxes
        figure3                       matlab.ui.control.UIAxes
        ProceedButton                 matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            [file, folder] = uigetfile(...    
                {'*.jpg; *.JPG; *.jpeg; *.JPEG; *.img; *.IMG; *.tif; *.TIF; *.tiff, *.TIFF','Supported Files (*.jpg,*.img,*.tiff,)'; ...
                '*.jpg','jpg Files (*.jpg)';...
                '*.JPG','JPG Files (*.JPG)';...
                '*.jpeg','jpeg Files (*.jpeg)';...
                '*.JPEG','JPEG Files (*.JPEG)';...
                '*.png','png Files (*.png)';...
                '*.PNG','PNG Files (*.PNG)';...
                '*.img','img Files (*.img)';...
                '*.IMG','IMG Files (*.IMG)';...
                '*.tif','tif Files (*.tif)';...
                '*.TIF','TIF Files (*.TIF)';...
                '*.tiff','tiff Files (*.tiff)';...
                '*.TIFF','TIFF Files (*.TIFF)'},...    
                'MultiSelect', 'off');
            global fullpath;
            fullpath = string(folder) + string(file);
            app.ImageDirectoryEditField.Value=fullpath;
        end

        % Size changed function: GeometricTransformationPanel
        function GeometricTransformationPanelSizeChanged(app, event)

        end

        % Button pushed function: ProceedButton
        function ProceedButtonPushed(app, event)
            % Figure 1
            global fullpath;
            fullpath=app.ImageDirectoryEditField.Value;
            original = (imread(convertStringsToChars(fullpath)));
            global originalimage;
            originalimage=(original); % Saving original image for global use
            set(app.figure1, 'Units', 'pixels');
            imshow(original, 'parent', app.figure1)
            
            % Figure 2
            global geometrictransformed;
            theta=5;

            % This will rotate the image around the center
            % It works in counter clockwise direction
            rotation=[cosd(theta) sind(theta) 0; -sind(theta) cosd(theta) 0; 0 0 1];
            
            ApplyGeometricTransformation( rotation );
            
            set(app.figure2, 'Units', 'pixels');
            imshow(geometrictransformed, [], 'parent', app.figure2)
            
            % Figure 3
            global restoredimage;
            
            RestoreGeometricTransformation();
            
            set(app.figure3,'Units','pixels');
            imshow(restoredimage, [], 'parent', app.figure3)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.ImageDirecoryLabel = uilabel(app.UIFigure);
            app.ImageDirecoryLabel.Text = 'Image Direcory:';
            app.ImageDirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.ImageDirectoryEditField.Value = 'cameraman.tif';
            app.GeometricTransformationPanel = uipanel(app.UIFigure);
            app.GeometricTransformationPanel.Title = 'Geometric Transformation';
            app.GeometricTransformationPanel.SizeChangedFcn = createCallbackFcn(app, @GeometricTransformationPanelSizeChanged, true);
            app.figure1 = uiaxes(app.GeometricTransformationPanel);
            title(app.figure1, 'Original')
            xlabel(app.figure1, 'X')
            ylabel(app.figure1, 'Y')
            app.figure2 = uiaxes(app.GeometricTransformationPanel);
            title(app.figure2, 'Geometric Transformation Appied')
            xlabel(app.figure2, 'X')
            ylabel(app.figure2, 'Y')
            app.figure3 = uiaxes(app.GeometricTransformationPanel);
            title(app.figure3, 'Restored Image')
            xlabel(app.figure3, 'X')
            ylabel(app.figure3, 'Y')
            app.ProceedButton = uibutton(app.GeometricTransformationPanel, 'push');
            app.ProceedButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedButtonPushed, true);
            app.ProceedButton.Text = 'Proceed';
        end
    end

    methods (Access = public)

        % Construct app
        function app = interface

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end